classdef SixBarPickAndPlaceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GLabel                         matlab.ui.control.Label
        FLabel                         matlab.ui.control.Label
        ELabel                         matlab.ui.control.Label
        DLabel                         matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        BLabel                         matlab.ui.control.Label
        ALabel                         matlab.ui.control.Label
        YmLabel                        matlab.ui.control.Label
        JointLabel                     matlab.ui.control.Label
        XmLabel                        matlab.ui.control.Label
        ProgressLamp                   matlab.ui.control.Lamp
        ProgressLabel                  matlab.ui.control.Label
        DownloadDataButton             matlab.ui.control.Button
        ShowMovementButton             matlab.ui.control.Button
        ComputePositionAnalysisButton  matlab.ui.control.Button
        GenerateLinkageButton          matlab.ui.control.Button
        GyEditField                    matlab.ui.control.NumericEditField
        GxEditField                    matlab.ui.control.NumericEditField
        FyEditField                    matlab.ui.control.NumericEditField
        FxEditField                    matlab.ui.control.NumericEditField
        EyEditField                    matlab.ui.control.NumericEditField
        ExEditField                    matlab.ui.control.NumericEditField
        DyEditField                    matlab.ui.control.NumericEditField
        DxEditField                    matlab.ui.control.NumericEditField
        CyEditField                    matlab.ui.control.NumericEditField
        CxEditField                    matlab.ui.control.NumericEditField
        ByEditField                    matlab.ui.control.NumericEditField
        BxEditField                    matlab.ui.control.NumericEditField
        AyEditField                    matlab.ui.control.NumericEditField
        AxEditField                    matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NumSteps = 360
    
        NewBx
        NewBy
        NewCx
        NewCy
        NewEx
        NewEy
        NewFx
        NewFy
    
        LastIndex = 0
    end
    
    methods (Access = private)
        
        function initializePositionArrays(app)
            numberOfPositions = app.NumSteps + 1;
        
            app.NewBx = zeros(numberOfPositions, 1);
            app.NewBy = zeros(numberOfPositions, 1);
        
            app.NewCx = zeros(numberOfPositions, 1);
            app.NewCy = zeros(numberOfPositions, 1);
        
            app.NewEx = zeros(numberOfPositions, 1);
            app.NewEy = zeros(numberOfPositions, 1);
        
            app.NewFx = zeros(numberOfPositions, 1);
            app.NewFy = zeros(numberOfPositions, 1);
        
            app.LastIndex = 0;
        end
        
        function [A, B, C, D, E, F, G] = readJointCoordinates(app)
            A = [app.AxEditField.Value, app.AyEditField.Value, 0];
            B = [app.BxEditField.Value, app.ByEditField.Value, 0];
            C = [app.CxEditField.Value, app.CyEditField.Value, 0];
            D = [app.DxEditField.Value, app.DyEditField.Value, 0];
            E = [app.ExEditField.Value, app.EyEditField.Value, 0];
            F = [app.FxEditField.Value, app.FyEditField.Value, 0];
            G = [app.GxEditField.Value, app.GyEditField.Value, 0];
        end

        function plotLinkage(app, A, B, C, D, E, F, G)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
        
            % Link AB
            plot(app.UIAxes, [A(1), B(1)], [A(2), B(2)], 'k-', 'LineWidth', 2);
            % Link BC
            plot(app.UIAxes, [B(1), C(1)], [B(2), C(2)], 'k-', 'LineWidth', 2);
            % Link CDE
            plot(app.UIAxes, [C(1), D(1)], [C(2), D(2)], 'k-', 'LineWidth', 2);
            plot(app.UIAxes, [C(1), E(1)], [C(2), E(2)], 'k-', 'LineWidth', 2);
            plot(app.UIAxes, [D(1), E(1)], [D(2), E(2)], 'k-', 'LineWidth', 2);
            % Link EF
            plot(app.UIAxes, [E(1), F(1)], [E(2), F(2)], 'k-', 'LineWidth', 2);
            % Link FG
            plot(app.UIAxes, [F(1), G(1)], [F(2), G(2)], 'k-', 'LineWidth', 2);
            % Fixed joints A, D, and G
            plot(app.UIAxes, [A(1), D(1), G(1)], [A(2), D(2), G(2)], 'ks', 'MarkerFaceColor', 'k', 'MarkerSize', 7);
            % Moving joints B, C, E, and F
            plot(app.UIAxes, [B(1), C(1), E(1), F(1)], [B(2), C(2), E(2), F(2)], 'ko', 'MarkerFaceColor', 'w', 'MarkerSize', 7);
        
            text(app.UIAxes, A(1), A(2), '  A');
            text(app.UIAxes, B(1), B(2), '  B');
            text(app.UIAxes, C(1), C(2), '  C');
            text(app.UIAxes, D(1), D(2), '  D');
            text(app.UIAxes, E(1), E(2), '  E');
            text(app.UIAxes, F(1), F(2), '  F');
            text(app.UIAxes, G(1), G(2), '  G');
        
            allX = [A(1), B(1), C(1), D(1), E(1), F(1), G(1)];
            allY = [A(2), B(2), C(2), D(2), E(2), F(2), G(2)];
        
            app.setAxesLimits(allX, allY);
        
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
        
            title(app.UIAxes, 'Pick-and-Place Six-Bar Linkage');
            xlabel(app.UIAxes, 'X Coordinate (m)');
            ylabel(app.UIAxes, 'Y Coordinate (m)');
        
            hold(app.UIAxes, 'off');
        end
        
        function setAxesLimits(app, xValues, yValues)
            xSpan = max(xValues) - min(xValues);
            ySpan = max(yValues) - min(yValues);
        
            xMargin = max(0.15, 0.10*xSpan);
            yMargin = max(0.15, 0.10*ySpan);
        
            xlim(app.UIAxes, [min(xValues) - xMargin, max(xValues) + xMargin]);
            ylim(app.UIAxes, [min(yValues) - yMargin, max(yValues) + yMargin]);
        end
        
        function intersectionPoints = circleIntersection(app, center1, radius1, center2, radius2)
            intersectionPoints = [];
            tolerance = 1e-10;
        
            centerVector = center2(1:2) - center1(1:2);
            centerDistance = norm(centerVector);
        
            if centerDistance > radius1 + radius2 + tolerance
                return;
            end
        
            if centerDistance < abs(radius1 - radius2) - tolerance
                return;
            end
        
            if centerDistance < tolerance
                return;
            end
        
            distanceAlongLine = (radius1^2 - radius2^2 + centerDistance^2) / (2*centerDistance);
            heightSquared = radius1^2 - distanceAlongLine^2;
        
            if heightSquared < -tolerance
                return;
            end
        
            intersectionHeight = sqrt(max(heightSquared, 0));
            unitVector = centerVector / centerDistance;
            basePoint = center1(1:2) + distanceAlongLine*unitVector;
            perpendicularVector = [-unitVector(2), unitVector(1)];
        
            point1 = basePoint + intersectionHeight*perpendicularVector;
            point2 = basePoint - intersectionHeight*perpendicularVector;
        
            if intersectionHeight < tolerance
                intersectionPoints = point1;
            else
                intersectionPoints = [point1; point2];
            end
        end
        
        function selectedPoint = chooseClosestIntersection(app, intersectionPoints, previousPoint)
            differences = intersectionPoints - previousPoint(1:2);
            distances = sqrt(sum(differences.^2, 2));
            [~, closestIndex] = min(distances);
            selectedPoint = [intersectionPoints(closestIndex, :), 0];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializePositionArrays();

            app.ComputePositionAnalysisButton.Enable = 'off';
            app.ShowMovementButton.Enable = 'off';
            app.DownloadDataButton.Enable = 'off';

            app.ProgressLabel.Visible = 'off';
            app.ProgressLamp.Visible = 'off';
            app.ProgressLamp.Color = [0.65 0.65 0.65];
        end

        % Button pushed function: GenerateLinkageButton
        function GenerateLinkageButtonPushed(app, event)
            [A, B, C, D, E, F, G] = app.readJointCoordinates();

            app.plotLinkage(A, B, C, D, E, F, G);
            app.initializePositionArrays();

            app.ComputePositionAnalysisButton.Enable = 'on';
            app.ShowMovementButton.Enable = 'off';
            app.DownloadDataButton.Enable = 'off';

            app.ProgressLabel.Visible = 'on';
            app.ProgressLamp.Visible = 'on';
            app.ProgressLabel.Text = 'Progress: Ready';
            app.ProgressLamp.Color = [0.65 0.65 0.65];
        end

        % Button pushed function: ComputePositionAnalysisButton
        function ComputePositionAnalysisButtonPushed(app, event)
            [A, B, C, D, E, F, G] = app.readJointCoordinates();
            
            app.initializePositionArrays();
            
            app.ProgressLabel.Visible = 'on';
            app.ProgressLamp.Visible = 'on';
            app.ProgressLabel.Text = 'Progress: Running';
            app.ProgressLamp.Color = [0 0 1];
            
            app.ShowMovementButton.Enable = 'off';
            app.DownloadDataButton.Enable = 'off';
            
            drawnow;
            
            %% Constant link lengths
            AB = norm(B - A);
            BC = norm(C - B);
            CD = norm(C - D);
            DE = norm(E - D);
            EF = norm(F - E);
            FG = norm(F - G);
            
            %% Initial angular relationships
            initialAngle = atan2(B(2) - A(2), B(1) - A(1));
            initialDCAngle = atan2(C(2) - D(2), C(1) - D(1));
            initialDEAngle = atan2(E(2) - D(2), E(1) - D(1));
            CDEAngleOffset = initialDEAngle - initialDCAngle;
            
            %% Store the initial position
            app.NewBx(1) = B(1);
            app.NewBy(1) = B(2);
            
            app.NewCx(1) = C(1);
            app.NewCy(1) = C(2);
            
            app.NewEx(1) = E(1);
            app.NewEy(1) = E(2);
            
            app.NewFx(1) = F(1);
            app.NewFy(1) = F(2);
            
            previousC = C;
            previousF = F;
            
            app.LastIndex = 1;
            
            stoppedJoint = '';
            stoppedAngle = NaN;
            
            %% Position analysis
            for theta = 1:app.NumSteps
                currentAngle = initialAngle + deg2rad(theta);
                BNew = [A(1) + AB*cos(currentAngle), A(2) + AB*sin(currentAngle), 0];
                CIntersections = app.circleIntersection(BNew, BC, D, CD);
            
                if isempty(CIntersections)
                    stoppedJoint = 'C';
                    stoppedAngle = theta;
                    break;
                end
            
                CNew = app.chooseClosestIntersection(CIntersections, previousC);
                currentDCAngle = atan2(CNew(2) - D(2), CNew(1) - D(1));
                currentDEAngle = currentDCAngle + CDEAngleOffset;
                ENew = [D(1) + DE*cos(currentDEAngle), D(2) + DE*sin(currentDEAngle), 0];
            
                FIntersections = app.circleIntersection(ENew, EF, G, FG);
            
                if isempty(FIntersections)
                    stoppedJoint = 'F';
                    stoppedAngle = theta;
                    break;
                end
            
                FNew = app.chooseClosestIntersection(FIntersections, previousF);
            
                index = theta + 1;
            
                app.NewBx(index) = BNew(1);
                app.NewBy(index) = BNew(2);
            
                app.NewCx(index) = CNew(1);
                app.NewCy(index) = CNew(2);
            
                app.NewEx(index) = ENew(1);
                app.NewEy(index) = ENew(2);
            
                app.NewFx(index) = FNew(1);
                app.NewFy(index) = FNew(2);
            
                app.LastIndex = index;
            
                previousC = CNew;
                previousF = FNew;
            end
            
            %% Update the interface
            app.ProgressLamp.Color = [0 1 0];
            
            if isempty(stoppedJoint)
                app.ProgressLabel.Text = sprintf('Progress: Complete (%d positions)', app.LastIndex);
            else
                app.ProgressLabel.Text = sprintf('Complete: joint %s stopped at %d degrees', stoppedJoint, stoppedAngle);
            end
            
            if app.LastIndex > 1
                app.ShowMovementButton.Enable = 'on';
            end
        end

        % Button pushed function: ShowMovementButton
        function ShowMovementButtonPushed(app, event)
            if app.LastIndex < 1
                return;
            end
            
            [A, ~, ~, D, ~, ~, G] = app.readJointCoordinates();
            
            validIndices = 1:app.LastIndex;
            
            %% Determine fixed animation limits
            allX = [A(1); D(1); G(1); app.NewBx(validIndices); app.NewCx(validIndices); app.NewEx(validIndices); app.NewFx(validIndices)];
            allY = [A(2); D(2); G(2); app.NewBy(validIndices); app.NewCy(validIndices); app.NewEy(validIndices); app.NewFy(validIndices)];
            
            xSpan = max(allX) - min(allX);
            ySpan = max(allY) - min(allY);
            
            xMargin = max(0.15, 0.10*xSpan);
            yMargin = max(0.15, 0.10*ySpan);
            
            xLimits = [min(allX) - xMargin, max(allX) + xMargin];
            yLimits = [min(allY) - yMargin, max(allY) + yMargin];
            
            %% Animate the linkage
            for index = validIndices
                B = [app.NewBx(index), app.NewBy(index), 0];
                C = [app.NewCx(index), app.NewCy(index), 0];
                E = [app.NewEx(index), app.NewEy(index), 0];
                F = [app.NewFx(index), app.NewFy(index), 0];
            
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
            
                % Link AB
                plot(app.UIAxes, [A(1), B(1)], [A(2), B(2)], 'k-', 'LineWidth', 2);
            
                % Link BC
                plot(app.UIAxes, [B(1), C(1)], [B(2), C(2)], 'k-', 'LineWidth', 2);
            
                % Rigid link CDE
                plot(app.UIAxes, [C(1), D(1)], [C(2), D(2)], 'k-', 'LineWidth', 2);
                plot(app.UIAxes, [C(1), E(1)], [C(2), E(2)], 'k-', 'LineWidth', 2);
                plot(app.UIAxes, [D(1), E(1)], [D(2), E(2)], 'k-', 'LineWidth', 2);
            
                % Link EF
                plot(app.UIAxes, [E(1), F(1)], [E(2), F(2)], 'k-', 'LineWidth', 2);
            
                % Link FG
                plot(app.UIAxes, [F(1), G(1)], [F(2), G(2)], 'k-', 'LineWidth', 2);
            
                % Fixed joints
                plot(app.UIAxes, [A(1), D(1), G(1)], [A(2), D(2), G(2)], 'ks', 'MarkerFaceColor', 'k', 'MarkerSize', 7);
            
                % Moving joints
                plot(app.UIAxes, [B(1), C(1), E(1), F(1)], [B(2), C(2), E(2), F(2)], 'ko', 'MarkerFaceColor', 'w', 'MarkerSize', 7);
            
                axis(app.UIAxes, 'equal');
                xlim(app.UIAxes, xLimits);
                ylim(app.UIAxes, yLimits);
            
                grid(app.UIAxes, 'on');
                box(app.UIAxes, 'on');
            
                title(app.UIAxes, 'Pick-and-Place Six-Bar Linkage Animation');
                xlabel(app.UIAxes, 'X Coordinate (m)');
                ylabel(app.UIAxes, 'Y Coordinate (m)');
            
                hold(app.UIAxes, 'off');
            
                drawnow;
                pause(0.02);
            end
            
            app.DownloadDataButton.Enable = 'on';
        end

        % Button pushed function: DownloadDataButton
        function DownloadDataButtonPushed(app, event)
            if app.LastIndex < 1
                return;
            end
            
            validIndices = 1:app.LastIndex;
            
            PositionData = table( ...
                app.NewBx(validIndices), ...
                app.NewBy(validIndices), ...
                app.NewCx(validIndices), ...
                app.NewCy(validIndices), ...
                app.NewEx(validIndices), ...
                app.NewEy(validIndices), ...
                app.NewFx(validIndices), ...
                app.NewFy(validIndices), ...
                'VariableNames', { ...
                'B_x_m', ...
                'B_y_m', ...
                'C_x_m', ...
                'C_y_m', ...
                'E_x_m', ...
                'E_y_m', ...
                'F_x_m', ...
                'F_y_m'});

            [file, path] = uiputfile('*.csv', 'Save Position Data', 'six_bar_position_data.csv');
            
            if isequal(file, 0)
                return;
            end
            
            writetable(PositionData, fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Six-Bar Pick-and-Place Linkage Analysis';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pick-and-Place Six-Bar Linkage')
            xlabel(app.UIAxes, 'X Coordinate (m)')
            ylabel(app.UIAxes, 'Y Coordinate (m)')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Joint Coordinates and Controls';
            app.AxEditField = uieditfield(app.UIFigure, 'numeric');
            app.AxEditField.ValueDisplayFormat = '%.3f';
            app.AxEditField.Value = 1.4;
            app.AyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AyEditField.ValueDisplayFormat = '%.3f';
            app.AyEditField.Value = 0.485;
            app.BxEditField = uieditfield(app.UIFigure, 'numeric');
            app.BxEditField.ValueDisplayFormat = '%.3f';
            app.BxEditField.Value = 1.67;
            app.ByEditField = uieditfield(app.UIFigure, 'numeric');
            app.ByEditField.ValueDisplayFormat = '%.3f';
            app.ByEditField.Value = 0.99;
            app.CxEditField = uieditfield(app.UIFigure, 'numeric');
            app.CxEditField.ValueDisplayFormat = '%.3f';
            app.CxEditField.Value = 0.255;
            app.CyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CyEditField.ValueDisplayFormat = '%.3f';
            app.CyEditField.Value = 1.035;
            app.DxEditField = uieditfield(app.UIFigure, 'numeric');
            app.DxEditField.ValueDisplayFormat = '%.3f';
            app.DxEditField.Value = 0.285;
            app.DyEditField = uieditfield(app.UIFigure, 'numeric');
            app.DyEditField.ValueDisplayFormat = '%.3f';
            app.DyEditField.Value = 0.055;
            app.ExEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExEditField.ValueDisplayFormat = '%.3f';
            app.ExEditField.Value = 0.195;
            app.EyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EyEditField.ValueDisplayFormat = '%.3f';
            app.EyEditField.Value = 2.54;
            app.FxEditField = uieditfield(app.UIFigure, 'numeric');
            app.FxEditField.ValueDisplayFormat = '%.3f';
            app.FxEditField.Value = -0.98;
            app.FyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FyEditField.ValueDisplayFormat = '%.3f';
            app.FyEditField.Value = 2.57;
            app.GxEditField = uieditfield(app.UIFigure, 'numeric');
            app.GxEditField.ValueDisplayFormat = '%.3f';
            app.GxEditField.Value = 0.05;
            app.GyEditField = uieditfield(app.UIFigure, 'numeric');
            app.GyEditField.ValueDisplayFormat = '%.3f';
            app.GyEditField.Value = 0.2;
            app.GenerateLinkageButton = uibutton(app.UIFigure, 'push');
            app.GenerateLinkageButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateLinkageButtonPushed, true);
            app.GenerateLinkageButton.Text = 'Generate Linkage';
            app.ComputePositionAnalysisButton = uibutton(app.UIFigure, 'push');
            app.ComputePositionAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ComputePositionAnalysisButtonPushed, true);
            app.ComputePositionAnalysisButton.Enable = 'off';
            app.ComputePositionAnalysisButton.Text = 'Compute Position Analysis';
            app.ShowMovementButton = uibutton(app.UIFigure, 'push');
            app.ShowMovementButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMovementButtonPushed, true);
            app.ShowMovementButton.Enable = 'off';
            app.ShowMovementButton.Text = 'Show Movement';
            app.DownloadDataButton = uibutton(app.UIFigure, 'push');
            app.DownloadDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDataButtonPushed, true);
            app.DownloadDataButton.Enable = 'off';
            app.DownloadDataButton.Text = 'Download Data';
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Visible = 'off';
            app.ProgressLabel.Text = 'Progress: Ready';
            app.ProgressLamp = uilamp(app.UIFigure);
            app.ProgressLamp.Visible = 'off';
            app.XmLabel = uilabel(app.UIFigure);
            app.XmLabel.Text = 'X (m)';
            app.JointLabel = uilabel(app.UIFigure);
            app.JointLabel.Text = 'Joint';
            app.YmLabel = uilabel(app.UIFigure);
            app.YmLabel.Text = 'Y (m)';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = 'C';
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = 'D';
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Text = 'E';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F';
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = 'G';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SixBarPickAndPlaceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end